classdef ImageFilter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ImageBox            matlab.ui.control.UIAxes
        ColortoFIlterLabel  matlab.ui.control.Label
        DropDown            matlab.ui.control.DropDown
        LoadButton          matlab.ui.control.Button
        maskBox             matlab.ui.control.UIAxes
        filterBox           matlab.ui.control.UIAxes
        OriginalImageLabel  matlab.ui.control.Label
        ScaledMaskLabel     matlab.ui.control.Label
        FilteredImageLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Array of colors
        colors = ["RED","ORANGE","YELLOW","GREEN","BLUE","VIOLET"]
        
        % RGB Image
        rgbImage
        
        % Mask
        BW
        
        % Masked Image
        maskedRGBImage
    end
    
    
    methods (Access = private)
        
        function updateimage(app,imagefile)
            try
                app.rgbImage = imread(imagefile);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end
            
            % Display the truecolor image
            imagesc(app.ImageBox,app.rgbImage);
            filter(app)
            
        end
        
        function [BW,maskedRGBImage] = createMask(~,RGB,color)
            %createMask  Threshold RGB image using auto-generated code from colorThresholder app.
            %  The segmentation mask is returned in BW, and a composite of the mask and
            %  original RGB images is returned in maskedRGBImage.
            
            % Convert RGB image to chosen color space
            I = rgb2hsv(RGB);
            
            if color == "RED"
                % Define thresholds for Hue
                hueMin = 0.942;
                hueMax = 0.042;
                
                % Define thresholds for Saturation
                saturationMin = 0.000;
                saturationMax = 1.000;
                
                % Define thresholds for Value
                valueMin = 0.000;
                valueMax = 1.000;
                
                % Create mask based on chosen histogram thresholds
                sliderBW = ( (I(:,:,1) >= hueMin) | (I(:,:,1) <= hueMax) ) & ...
                    (I(:,:,2) >=saturationMin ) & (I(:,:,2) <=saturationMax) & ...
                    (I(:,:,3) >= valueMin ) & (I(:,:,3) <= valueMax);
                BW = sliderBW;
            end
            
            if color == "ORANGE"
                hueMin = 1.000;
                hueMax = 0.133;
                
                % Define thresholds for Saturation
                saturationMin = 0.000;
                saturationMax = 1.000;
                
                % Define thresholds for Value
                valueMin = 0.000;
                valueMax = 1.000;
                
                % Create mask based on chosen histogram thresholds
                sliderBW = ( (I(:,:,1) >= hueMin) | (I(:,:,1) <= hueMax) ) & ...
                    (I(:,:,2) >=saturationMin ) & (I(:,:,2) <=saturationMax) & ...
                    (I(:,:,3) >= valueMin ) & (I(:,:,3) <= valueMax);
                BW = sliderBW;
            end
            
            if color == "YELLOW"
                % Define thresholds for Hue
                hueMin = 0.140;
                hueMax = 0.228;
                
                % Define thresholds for Saturation
                saturationMin = 0.000;
                saturationMax = 1.000;
                
                % Define thresholds for Value
                valueMin = 0.000;
                valueMax = 1.000;
                
                % Create mask based on chosen histogram thresholds
                sliderBW = (I(:,:,1) >= hueMin ) & (I(:,:,1) <= hueMax) & ...
                    (I(:,:,2) >=saturationMin ) & (I(:,:,2) <=saturationMax) & ...
                    (I(:,:,3) >= valueMin ) & (I(:,:,3) <= valueMax);
                BW = sliderBW;
                
            end
            
            if color == "GREEN"
                % Convert RGB image to chosen color space
                I = rgb2hsv(RGB);
                
                % Define thresholds for Hue
                hueMin = 0.213;
                hueMax = 0.487;
                
                % Define thresholds for Saturation
                saturationMin = 0.000;
                saturationMax = 1.000;
                
                % Define thresholds for Value
                valueMin = 0.000;
                valueMax = 1.000;
                
                % Create mask based on chosen histogram thresholds
                sliderBW = (I(:,:,1) >= hueMin ) & (I(:,:,1) <= hueMax) & ...
                    (I(:,:,2) >=saturationMin ) & (I(:,:,2) <=saturationMax) & ...
                    (I(:,:,3) >= valueMin ) & (I(:,:,3) <= valueMax);
                BW = sliderBW;
            end
            
            if color == "BLUE"
                hueMin = 0.494;
                hueMax = 0.782;
                
                % Define thresholds for Saturation
                saturationMin = 0.000;
                saturationMax = 1.000;
                
                % Define thresholds for Value
                valueMin = 0.000;
                valueMax = 1.000;
                
                % Create mask based on chosen histogram thresholds
                sliderBW = (I(:,:,1) >= hueMin ) & (I(:,:,1) <= hueMax) & ...
                    (I(:,:,2) >=saturationMin ) & (I(:,:,2) <=saturationMax) & ...
                    (I(:,:,3) >= valueMin ) & (I(:,:,3) <= valueMax);
                BW = sliderBW;
            end
            
            if color == "VIOLET"
                % Define thresholds for Hue
                hueMin = 0.768;
                hueMax = 0.942;
                
                % Define thresholds for Saturation
                saturationMin = 0.000;
                saturationMax = 1.000;
                
                % Define thresholds for Value
                valueMin = 0.000;
                valueMax = 1.000;
                
                % Create mask based on chosen histogram thresholds
                sliderBW = (I(:,:,1) >= hueMin ) & (I(:,:,1) <= hueMax) & ...
                    (I(:,:,2) >= saturationMin ) & (I(:,:,2) <= saturationMax) & ...
                    (I(:,:,3) >= valueMin ) & (I(:,:,3) <= valueMax);
                BW = sliderBW;
            end
            
            % Initialize output masked image based on input image.
            maskedRGBImage = RGB;
            
            % Set background pixels where BW is false to zero.
            maskedRGBImage(repmat(~BW,[1 1 3])) = 0;
            
        end
        function filter(app)
            [app.BW,app.maskedRGBImage] = createMask(app,app.rgbImage,app.DropDown.Value);
            
            imagesc(app.maskBox,  app.BW);
            imagesc(app.filterBox,app.maskedRGBImage);
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set DropdownItems
            app.DropDown.Items = app.colors;
            
            % Update the image and histograms
            updateimage(app, 'test.png');
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            filter(app)
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                fname = [p f];
                updateimage(app, fname);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Filter';
            app.ImageBox = uiaxes(app.UIFigure);
            app.ImageBox.XTick = [];
            app.ImageBox.XTickLabel = {'[ ]'};
            app.ImageBox.YTick = [];
            app.ColortoFIlterLabel = uilabel(app.UIFigure);
            app.ColortoFIlterLabel.Text = 'Color to FIlter:';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = {};
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Custom Image';
            app.maskBox = uiaxes(app.UIFigure);
            app.maskBox.XTick = [];
            app.maskBox.XTickLabel = {'[ ]'};
            app.maskBox.YTick = [];
            app.filterBox = uiaxes(app.UIFigure);
            app.filterBox.XTick = [];
            app.filterBox.XTickLabel = {'[ ]'};
            app.filterBox.YTick = [];
            app.OriginalImageLabel = uilabel(app.UIFigure);
            app.OriginalImageLabel.Text = 'Original Image';
            app.ScaledMaskLabel = uilabel(app.UIFigure);
            app.ScaledMaskLabel.Text = 'Scaled Mask';
            app.FilteredImageLabel = uilabel(app.UIFigure);
            app.FilteredImageLabel.Text = 'Filtered Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageFilter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end